classdef DiceGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        dice2           matlab.ui.control.Image
        dice3           matlab.ui.control.Image
        dice4           matlab.ui.control.Image
        dice6           matlab.ui.control.Image
        dice5           matlab.ui.control.Image
        dice1           matlab.ui.control.Image
        RollDiceButton  matlab.ui.control.Button
        PlayButton      matlab.ui.control.Button
        secondbg        matlab.ui.control.Image
        menubg          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Start(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.menubg.Visible = "off";
            app.secondbg.Visible = "on";

            % okedjbioewhfipehipf

            % making dice on and roll button on
            %diceImages = {"one.png","two.png","three.png","four.png","five.png","six.png"};
            %app.Image6.ImageSource = "one.png";
            % for k= 1:numel(diceImages)
            %     app.Image5.ImageSource = diceImages(k);
            %     % change it every 0.1 seconds or so to simulate animation
            % end
            app.RollDiceButton.Visible = "on"
            app.dice1.Visible = "on";
            app.dice2.Visible = "off";
            app.dice3.Visible = "off";
            app.dice4.Visible = "off";
            app.dice5.Visible = "off";
            app.dice6.Visible = "off";

            %app.dice5.Visible = "on"; % FIXED: Added missing semicolon
            
            % Disable and make Play and Help buttons invisible
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)


            % Group your 6 image components into a cell array
          diceComponents = {app.dice1, app.dice2, app.dice3, ...
                              app.dice4, app.dice5, app.dice6};
                          
            % Number of frames to shuffle
            numShuffles = 20; 
            
            % Helper function to hide all dice images
            for k = 1:6
                diceComponents{k}.Visible = "off";
            end
            
            % Shuffle animation loop
            for i = 1:numShuffles
                % Generate a random dice index (1 through 6)
                currentRoll = randi([1, 6]); 
                
                % Flash the current random dice image on
                diceComponents{currentRoll}.Visible = "on";
                
                % Pause briefly to create the illusion of rolling
                pause(0.08);
                
                % Turn it back off before the next iteration
                diceComponents{currentRoll}.Visible = "off";
                
                % Force the UI to refresh immediately
                drawnow(); 
            end
            
            % Determine and display the final roll
            finalRoll = randi([1, 6]);
            diceComponents{finalRoll}.Visible = "on";
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menubg = uiimage(app.UIFigure);
            app.menubg.ImageSource = fullfile(pathToMLAPP, 'backgroundgame.png');
            app.secondbg = uiimage(app.UIFigure);
            app.secondbg.Visible = 'off';
            app.secondbg.ImageSource = fullfile(pathToMLAPP, 'bg2.png');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Visible = 'off';
            app.RollDiceButton.Text = 'Roll Dice';
            app.dice1 = uiimage(app.UIFigure);
            app.dice1.Visible = 'off';
            app.dice1.ImageSource = fullfile(pathToMLAPP, 'one.png');
            app.dice5 = uiimage(app.UIFigure);
            app.dice5.Visible = 'off';
            app.dice5.ImageSource = fullfile(pathToMLAPP, 'five.png');
            app.dice6 = uiimage(app.UIFigure);
            app.dice6.Visible = 'off';
            app.dice6.ImageSource = fullfile(pathToMLAPP, 'six.png');
            app.dice4 = uiimage(app.UIFigure);
            app.dice4.Visible = 'off';
            app.dice4.ImageSource = fullfile(pathToMLAPP, 'four.png');
            app.dice3 = uiimage(app.UIFigure);
            app.dice3.Visible = 'off';
            app.dice3.ImageSource = fullfile(pathToMLAPP, 'two.png');
            app.dice2 = uiimage(app.UIFigure);
            app.dice2.Visible = 'off';
            app.dice2.ImageSource = fullfile(pathToMLAPP, 'two.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end